classdef prac8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Image                 matlab.ui.control.Image
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout3           matlab.ui.container.GridLayout
        BanderaDropDown       matlab.ui.control.DropDown
        BanderaDropDownLabel  matlab.ui.control.Label
        ProcesarButton        matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BanderaDropDown, Image
        function BanderaDropDownValueChanged(app, event)
            value = app.BanderaDropDown.Value;
            if(value == "Alemania")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Alemania.jpg";
                app.Image.ImageSource = ruta;
            elseif(value == "Belgica")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/belgica.png";
                app.Image.ImageSource = ruta;
            elseif(value == "Colombia")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/colombia.png";
                app.Image.ImageSource = ruta;
            elseif(value == "Holanda")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Holanda.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Italia")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Italia.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Japon")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/japon.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Irlanda")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Irlanda.jpeg";
                app.Image.ImageSource = ruta;
            elseif(value == "Luxemburgo")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/Luxemburgo.png";
                app.Image.ImageSource = ruta;
            elseif(value == "Qatar")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/qatar.png";
                app.Image.ImageSource = ruta;
            elseif(value == "Blanco")
                ruta = "C:/Users/19286463/Desktop/Escom/semestre actual/pattern/github/Matlab/practicas/Práctica_8/imagenes/blano.jpg";
                app.Image.ImageSource = ruta;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ProcesarButton = uibutton(app.GridLayout2, 'push');
            app.ProcesarButton.Text = 'Procesar';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BanderaDropDownLabel = uilabel(app.GridLayout3);
            app.BanderaDropDownLabel.Text = 'Bandera';
            app.BanderaDropDown = uidropdown(app.GridLayout3);
            app.BanderaDropDown.Items = {'Alemania', 'Belgica', 'Colombia', 'Holanda', 'Irlanda', 'Italia', 'Japon', 'Luxemburgo', 'Qatar', 'Blanco'};
            app.BanderaDropDown.ValueChangedFcn = createCallbackFcn(app, @BanderaDropDownValueChanged, true);
            app.BanderaDropDown.Placeholder = 'seleccionar';
            app.BanderaDropDown.Value = 'Alemania';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @BanderaDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prac8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end